classdef DataImportWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        ImportStatusL            matlab.ui.control.Label
        SheetNDD                 matlab.ui.control.DropDown
        SheetNameDropDownLabel   matlab.ui.control.Label
        FirstRowColNCB           matlab.ui.control.CheckBox
        FirstColRowNCB           matlab.ui.control.CheckBox
        SelectDatatoImportLabel  matlab.ui.control.Label
        DataSelTree              matlab.ui.container.CheckBoxTree
        ImportedDataT            matlab.ui.control.Table
        FileSelectL              matlab.ui.control.Label
        FileImportB              matlab.ui.control.Button
        FileSelectB              matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app Object
        filePath = "";
        importedData = {};
        colNodes = {};
        ImportDataB;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, path, importedData, importedSheet, colNames, ImportDataB)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.filePath = path;
            app.FileSelectL.Text = "File: "+string(path);
            try
                test95 = importedData
                app.ImportedDataT.Data = importedData;
            catch err
                err
                err.stack.line
            end
            app.importedData = importedData;
            app.ImportedDataT.ColumnName = colNames;
            app.FirstColRowNCB.Enable = false;
            app.FirstRowColNCB.Enable = false;
            app.ImportDataB = ImportDataB;
            try
                app.SheetNDD.Items = sheetnames(app.filePath);
                app.SheetNDD.Value = importedSheet;
            catch err
                err
            end

            % updating uitree
            for k=1:1:length(app.colNodes), delete(app.colNodes{k}); end
            app.colNodes = cell(1,length(colNames));
            for k=1:1:length(colNames)
                app.colNodes{k} = uitreenode(app.DataSelTree,"Text",colNames{k},"NodeData",1);
            end

            if ~isempty(importedData)
                app.FirstColRowNCB.Enable = true;
                app.FirstRowColNCB.Enable = true;
            end
        end

        % Button pushed function: FileImportB
        function FileImportBButtonPushed(app, event)
            finalImportedData = {};

            if app.FirstColRowNCB.Value && app.FirstRowColNCB.Value
                finalImportedData = app.importedData(2:end,2:end);
            elseif app.FirstColRowNCB.Value && ~app.FirstRowColNCB.Value
                finalImportedData = app.importedData(:,2:end);
            elseif ~app.FirstColRowNCB.Value && app.FirstRowColNCB.Value
                finalImportedData = app.importedData(2:end,:);
            elseif ~app.FirstColRowNCB.Value && ~app.FirstRowColNCB.Value
                finalImportedData = app.importedData;
            end

            importedSheet = app.SheetNDD.Value;
            updateImportedData(app.CallingApp,app.filePath,importedSheet,finalImportedData,app.ImportedDataT.ColumnName);

            app.ImportStatusL.Text = "Data Successfully Imported!";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.ImportDataB.Enable = true;

            % Delete the dialog box 
            delete(app);
        end

        % Button pushed function: FileSelectB
        function FileSelectBButtonPushed(app, event)
            [name,path] = uigetfile('*.xlsx');
            app.filePath = string(path)+string(name);
            app.FileSelectL.Text = "File: "+string(path)+string(name);

            % Call main app's public function
            try
                app.importedData = readtable(app.filePath);
                app.ImportedDataT.Data = app.importedData;
                colNames = cell(1,size(app.importedData,2));
                for k=1:1:size(app.importedData,2)
                    % colNames{k} = "Column "+k;
                    colNames{k} = app.importedData.Properties.VariableNames{k}
                end
                app.ImportedDataT.ColumnName = colNames;

                app.SheetNDD.Items = sheetnames(app.filePath);
            catch err
                throw(err);
            end

            app.FirstColRowNCB.Enable = true;
            app.FirstRowColNCB.Enable = true;

            % updating uitree
            for k=1:1:length(app.colNodes), delete(app.colNodes{k}); end
            app.colNodes = cell(1,length(colNames));
            for k=1:1:length(colNames)
                app.colNodes{k} = uitreenode(app.DataSelTree,"Text",colNames{k},"NodeData",1);
            end

            app.ImportStatusL.Text = "Data NOT Imported";
        end

        % Value changed function: FirstColRowNCB, FirstRowColNCB
        function RowColNUpdate(app, event)
            if app.FirstColRowNCB.Value && app.FirstRowColNCB.Value
                app.ImportedDataT.Data = app.importedData(2:end,2:end);
                % ### FIXME: fix column name updating
                app.ImportedDataT.ColumnName = cellstr(string(app.importedData{1,2:end}));
                % updating uitree
                for k=1:1:size(app.ImportedDataT.Data,2)+1
                    delete(app.colNodes{k});
                    if k < size(app.ImportedDataT.Data,2)+1
                        app.colNodes{k} = uitreenode(app.DataSelTree,"Text",string(app.importedData{1,k}));
                    end
                end
            elseif app.FirstColRowNCB.Value && ~app.FirstRowColNCB.Value
                app.ImportedDataT.Data = app.importedData(:,2:end);
                colNames = cell(1,size(app.importedData(1,2:end),2));
                for k=1:1:size(app.ImportedDataT.Data,2)
                    colNames{k} = "Column "+k;
                end
                app.ImportedDataT.ColumnName = colNames;
                % updating uitree
                for k=1:1:length(colNames)+1
                    delete(app.colNodes{k});
                    if k < length(colNames)+1
                        app.colNodes{k} = uitreenode(app.DataSelTree,"Text",colNames{k});
                    end
                end
            elseif ~app.FirstColRowNCB.Value && app.FirstRowColNCB.Value
                app.ImportedDataT.Data = app.importedData(2:end,:);
                app.ImportedDataT.ColumnName = cellstr(string(app.importedData{1,:}));
                % updating uitree
                for k=1:1:size(app.ImportedDataT.Data,2)
                    delete(app.colNodes{k});
                    app.colNodes{k} = uitreenode(app.DataSelTree,"Text",string(app.importedData{1,k}));
                end
            elseif ~app.FirstColRowNCB.Value && ~app.FirstRowColNCB.Value
                app.ImportedDataT.Data = app.importedData;
                colNames = cell(1,size(app.importedData,2));
                for k=1:1:size(app.ImportedDataT.Data,2)
                    colNames{k} = "Column "+k;
                end
                app.ImportedDataT.ColumnName = colNames;
                % updating uitree
                for k=1:1:length(colNames)
                    delete(app.colNodes{k});
                    app.colNodes{k} = uitreenode(app.DataSelTree,"Text",colNames{k});
                end
            end

            app.ImportStatusL.Text = "Data NOT Imported";
        end

        % Value changed function: SheetNDD
        function SheetNDDValueChanged(app, event)
            sheetName = app.SheetNDD.Value;
            
            % Call main app's public function
            try
                app.importedData = readtable(app.filePath,"Sheet",sheetName);
                app.ImportedDataT.Data = app.importedData;
                colNames = cell(1,size(app.importedData,2));
                for k=1:1:size(app.importedData,2)
                    colNames{k} = "Column "+k;
                end
                app.ImportedDataT.ColumnName = colNames;

            catch err
                throw(err);
            end

            app.FirstColRowNCB.Enable = true;
            app.FirstRowColNCB.Enable = true;

            % updating uitree
            for k=1:1:length(app.colNodes), delete(app.colNodes{k}); end
            app.colNodes = cell(1,length(colNames));
            for k=1:1:length(colNames)
                app.colNodes{k} = uitreenode(app.DataSelTree,"Text",colNames{k},"NodeData",1);
            end

            app.ImportStatusL.Text = "Data NOT Imported";
        end

        % Callback function: DataSelTree
        function DataSelTreeCheckedNodesChanged(app, event)
            app.ImportStatusL.Text = "Data NOT Imported";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FileSelectB = uibutton(app.GridLayout, 'push');
            app.FileSelectB.ButtonPushedFcn = createCallbackFcn(app, @FileSelectBButtonPushed, true);
            app.FileSelectB.Text = 'Select File';
            app.FileImportB = uibutton(app.GridLayout, 'push');
            app.FileImportB.ButtonPushedFcn = createCallbackFcn(app, @FileImportBButtonPushed, true);
            app.FileImportB.Text = 'Import';
            app.FileSelectL = uilabel(app.GridLayout);
            app.FileSelectL.Text = 'File: ';
            app.ImportedDataT = uitable(app.GridLayout);
            app.ImportedDataT.ColumnName = '';
            app.ImportedDataT.RowName = {};
            app.DataSelTree = uitree(app.GridLayout, 'checkbox');
            % Assign Checked Nodes
            app.DataSelTree.CheckedNodesChangedFcn = createCallbackFcn(app, @DataSelTreeCheckedNodesChanged, true);
            app.SelectDatatoImportLabel = uilabel(app.GridLayout);
            app.SelectDatatoImportLabel.Text = 'Select Data to Import';
            app.FirstColRowNCB = uicheckbox(app.GridLayout);
            app.FirstColRowNCB.ValueChangedFcn = createCallbackFcn(app, @RowColNUpdate, true);
            app.FirstColRowNCB.Tag = 'CR';
            app.FirstColRowNCB.Text = 'First Column as Row Names';
            app.FirstRowColNCB = uicheckbox(app.GridLayout);
            app.FirstRowColNCB.ValueChangedFcn = createCallbackFcn(app, @RowColNUpdate, true);
            app.FirstRowColNCB.Tag = 'RC';
            app.FirstRowColNCB.Text = 'First Row as Column Names';
            app.SheetNameDropDownLabel = uilabel(app.GridLayout);
            app.SheetNameDropDownLabel.Text = 'Sheet Name:';
            app.SheetNDD = uidropdown(app.GridLayout);
            app.SheetNDD.Items = {''};
            app.SheetNDD.ValueChangedFcn = createCallbackFcn(app, @SheetNDDValueChanged, true);
            app.SheetNDD.Value = '';
            app.ImportStatusL = uilabel(app.GridLayout);
            app.ImportStatusL.Text = 'Data NOT Imported';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataImportWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end