classdef RegAnalysisWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        nlparciP                       matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        ParCIRB                        matlab.ui.control.Button
        ParCIT                         matlab.ui.control.Table
        ParCIAlphaF                    matlab.ui.control.NumericEditField
        alphaLabel                     matlab.ui.control.Label
        ParCICalcTypeDD                matlab.ui.control.DropDown
        CalculateUsingDropDown_2Label  matlab.ui.control.Label
        NRMSEP                         matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        NRMSEL                         matlab.ui.control.Label
        NRMSET                         matlab.ui.control.Table
        NRMSECalcB                     matlab.ui.control.Button
        NRMSEDD                        matlab.ui.control.DropDown
        NormalizeByDropDownLabel       matlab.ui.control.Label
        nlpredciP                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        TValF                          matlab.ui.control.NumericEditField
        EvaluateattEditFieldLabel      matlab.ui.control.Label
        PredCIT                        matlab.ui.control.Table
        PredCIRB                       matlab.ui.control.Button
        PredIntTypeDD                  matlab.ui.control.DropDown
        PredictionIntervalTypeLabel    matlab.ui.control.Label
        PredCIAlphaF                   matlab.ui.control.NumericEditField
        alphaEditField_2Label          matlab.ui.control.Label
        SimBndsDD                      matlab.ui.control.DropDown
        SimultaneousBoundsLabel        matlab.ui.control.Label
        PredCICalcTypeDD               matlab.ui.control.DropDown
        CalculateUsingDropDownLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp
        NRMSEData
        NRMSENormType = "Range"
        ParCIData
        PredCIData
    end

    % ### FIXME: figure out how to do model weights for nlpredci
    
    methods (Access = public)
        function dispNRMSE(app,NRMSE)
            % ### FIXME: add functionality to show NRMSE for every DV
            % individually
            app.NRMSEData = NRMSE;
            app.NRMSEL.Text = "NRMSE by " + app.NRMSENormType;
            app.NRMSET.Data = NRMSE;
        end

        function dispParCI(app,parCIData)
            app.ParCIData = parCIData;
            app.ParCIT.Data = parCIData;
        end

        function dispPredCI(app,predCIData)
            app.PredCIData = predCIData;
            app.PredCIT.Data = predCIData;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, solver)
            app.CallingApp = mainapp;
            if strcmp(solver,'nlinfit')
                app.ParCICalcTypeDD.Items = {'Covariance','Jacobian'};
                app.PredCICalcTypeDD.Items = {'Covariance','Jacobian'};
            elseif strcmp(solver,'lsqcurvefit')
                app.ParCICalcTypeDD.Items = {'Jacobian'};
                app.PredCICalcTypeDD.Items = {'Jacobian'};
            else
                app.nlparciP.Enable = false;
                app.nlpredciP.Enable = false;
            end
        end

        % Value changed function: NRMSEDD
        function NRMSEDDValueChanged(app, event)
            app.NRMSENormType = app.NRMSEDD.Value;
        end

        % Button pushed function: NRMSECalcB
        function NRMSECalcBButtonPushed(app, event)
            app.CallingApp.runNRMSE(app.NRMSENormType);
        end

        % Button pushed function: ParCIRB
        function ParCIRBPushed(app, event)
            app.CallingApp.runParCI(app.ParCICalcTypeDD.Value,app.ParCIAlphaF.Value);
        end

        % Button pushed function: PredCIRB
        function PredCIRBButtonPushed(app, event)
            tval = double(app.TValF.Value);
            calcType = app.PredCICalcTypeDD.Value;
            alpha = double(app.PredCIAlphaF.Value);
            simBnds = app.SimBndsDD.Value;
            predIntType = app.PredIntTypeDD.Value;
            app.CallingApp.runPredCI(tval,calcType,alpha,simBnds,predIntType);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.nlpredciP = uipanel(app.GridLayout);
            app.nlpredciP.Title = 'Model Prediction Confidence Intervals';
            app.GridLayout3 = uigridlayout(app.nlpredciP);
            app.CalculateUsingDropDownLabel = uilabel(app.GridLayout3);
            app.CalculateUsingDropDownLabel.Text = 'Calculate Using:';
            app.PredCICalcTypeDD = uidropdown(app.GridLayout3);
            app.PredCICalcTypeDD.Items = {'Covariance', 'Jacobian'};
            app.PredCICalcTypeDD.Value = 'Covariance';
            app.SimultaneousBoundsLabel = uilabel(app.GridLayout3);
            app.SimultaneousBoundsLabel.Text = 'Simultaneous Bounds:';
            app.SimBndsDD = uidropdown(app.GridLayout3);
            app.SimBndsDD.Items = {'Off', 'On'};
            app.SimBndsDD.Value = 'Off';
            app.alphaEditField_2Label = uilabel(app.GridLayout3);
            app.alphaEditField_2Label.Interpreter = 'latex';
            app.alphaEditField_2Label.Text = '$\alpha =$ ';
            app.PredCIAlphaF = uieditfield(app.GridLayout3, 'numeric');
            app.PredCIAlphaF.Limits = [1e-06 0.999999];
            app.PredCIAlphaF.Value = 0.05;
            app.PredictionIntervalTypeLabel = uilabel(app.GridLayout3);
            app.PredictionIntervalTypeLabel.Text = 'Prediction Interval Type:';
            app.PredIntTypeDD = uidropdown(app.GridLayout3);
            app.PredIntTypeDD.Items = {'Curve', 'Observation'};
            app.PredIntTypeDD.Value = 'Curve';
            app.PredCIRB = uibutton(app.GridLayout3, 'push');
            app.PredCIRB.ButtonPushedFcn = createCallbackFcn(app, @PredCIRBButtonPushed, true);
            app.PredCIRB.Text = 'Run';
            app.PredCIT = uitable(app.GridLayout3);
            app.PredCIT.ColumnName = {'System Variable'; 'LL'; 'UL'; 'Unit'};
            app.PredCIT.RowName = {};
            app.EvaluateattEditFieldLabel = uilabel(app.GridLayout3);
            app.EvaluateattEditFieldLabel.Text = 'Evaluate at t = ';
            app.TValF = uieditfield(app.GridLayout3, 'numeric');
            app.NRMSEP = uipanel(app.GridLayout);
            app.NRMSEP.Title = 'Normalized Root Mean Squared Error and Coefficient of Determination';
            app.GridLayout2 = uigridlayout(app.NRMSEP);
            app.NormalizeByDropDownLabel = uilabel(app.GridLayout2);
            app.NormalizeByDropDownLabel.Text = 'Normalize By:';
            app.NRMSEDD = uidropdown(app.GridLayout2);
            app.NRMSEDD.Items = {'Range', 'Mean', 'Inter-Quartile Range', 'Median'};
            app.NRMSEDD.ValueChangedFcn = createCallbackFcn(app, @NRMSEDDValueChanged, true);
            app.NRMSEDD.Value = 'Range';
            app.NRMSECalcB = uibutton(app.GridLayout2, 'push');
            app.NRMSECalcB.ButtonPushedFcn = createCallbackFcn(app, @NRMSECalcBButtonPushed, true);
            app.NRMSECalcB.Text = 'Calculate';
            app.NRMSET = uitable(app.GridLayout2);
            app.NRMSET.ColumnName = {'Variable'; 'NRMSE'; 'R-Squared'};
            app.NRMSET.RowName = {};
            app.NRMSEL = uilabel(app.GridLayout2);
            app.NRMSEL.Interpreter = 'latex';
            app.NRMSEL.Text = 'Coefficient of Determination $(R^2) = $';
            app.nlparciP = uipanel(app.GridLayout);
            app.nlparciP.Title = 'Parameter Confidence Intervals';
            app.GridLayout4 = uigridlayout(app.nlparciP);
            app.CalculateUsingDropDown_2Label = uilabel(app.GridLayout4);
            app.CalculateUsingDropDown_2Label.Text = 'Calculate Using:';
            app.ParCICalcTypeDD = uidropdown(app.GridLayout4);
            app.ParCICalcTypeDD.Items = {'Covariance', 'Jacobian'};
            app.ParCICalcTypeDD.Value = 'Covariance';
            app.alphaLabel = uilabel(app.GridLayout4);
            app.alphaLabel.Interpreter = 'latex';
            app.alphaLabel.Text = '$\alpha =$ ';
            app.ParCIAlphaF = uieditfield(app.GridLayout4, 'numeric');
            app.ParCIAlphaF.Limits = [1e-06 0.999999];
            app.ParCIAlphaF.Value = 0.05;
            app.ParCIT = uitable(app.GridLayout4);
            app.ParCIT.ColumnName = {'Parameter'; 'LL'; 'UL'};
            app.ParCIT.RowName = {};
            app.ParCIRB = uibutton(app.GridLayout4, 'push');
            app.ParCIRB.ButtonPushedFcn = createCallbackFcn(app, @ParCIRBPushed, true);
            app.ParCIRB.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RegAnalysisWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end