classdef RegSpecsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        RegSpecsP                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        RegSpecDD1RB                   matlab.ui.control.Button
        RegSpecDD1AB                   matlab.ui.control.Button
        RegSpecSpin6                   matlab.ui.control.Spinner
        RegSpecL6                      matlab.ui.control.Label
        RegSpecL5                      matlab.ui.control.Label
        RegSpecL4                      matlab.ui.control.Label
        RegSpecL3                      matlab.ui.control.Label
        RegSpecL2                      matlab.ui.control.Label
        RegSpecL1                      matlab.ui.control.Label
        RegSolverDD                    matlab.ui.control.DropDown
        RegressionSolverDropDownLabel  matlab.ui.control.Label
        RegSpecUB                      matlab.ui.control.Button
        RegSpecCB7                     matlab.ui.control.CheckBox
        RegSpecSpin5                   matlab.ui.control.Spinner
        RegSpecDD2                     matlab.ui.control.DropDown
        RegSpecValL2                   matlab.ui.control.Label
        RegSpecDD1                     matlab.ui.control.DropDown
        RegSpecVF4                     matlab.ui.control.NumericEditField
        RegSpecVF3                     matlab.ui.control.NumericEditField
        RegSpecValL1                   matlab.ui.control.Label
        RegressionParameterSpecificationsPanel  matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        ParamIGVF4                     matlab.ui.control.EditField
        ParamIGL4                      matlab.ui.control.Label
        ParamIGL3                      matlab.ui.control.Label
        ParamIGVF3                     matlab.ui.control.NumericEditField
        ParamIGVF2                     matlab.ui.control.NumericEditField
        ParamIGL2                      matlab.ui.control.Label
        ParamIGVF1                     matlab.ui.control.NumericEditField
        InitialGuessLabel              matlab.ui.control.Label
        RegParamSpecUB                 matlab.ui.control.Button
        DerivStepL                     matlab.ui.control.Label
        RegParamDD                     matlab.ui.control.DropDown
        ParameterLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        regSpecs % struct of regression specifications
        regParamList % List of parameter names
        RegSpecB;
        regSpecLs = {};
        regSpecFs = {};
        nlinfitSpecs = struct( ...
            'RobustWgtFun','', ...
            'Tune',[], ...
            'TolFun',1E-8, ...
            'TolX',1E-8, ...
            'MaxIter',100, ...
            'FunValCheck','off', ...
            'DerivStep',[] ...
        );
        lsqcurvefitSpecs = struct( ...
            'paramBnds',[], ...
            'Algorithm','trust-region-reflective', ...
            'PlotFcns',[], ...
            'TolFun',1E-6, ...
            'TolX',1E-6, ...
            'MaxIter',1000, ...
            'MaxFunEvals',1000, ...
            'FunValCheck','off' ...
        );
        fminsearchSpecs = struct( ...
            'PlotFcns',[], ...
            'TolFun',1E-4, ...
            'TolX',1E-4, ...
            'MaxIter',1000, ...
            'MaxFunEvals',1000, ...
            'FunValCheck','off' ...
        );
        fminconSpecs = struct( ...
            'paramBnds',[], ...
            'Algorithm','interior-point', ...
            'PlotFcns',[], ...
            'TolFun',1E-6, ...
            'TolX',1E-6, ...
            'MaxIter',1000, ...
            'MaxFunEvals',1000, ...
            'FunValCheck','off' ...
        );
    end

    % ### FIXME: add error model functionality
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, regSpecs, regParamList, RegSpecB)
            app.CallingApp = mainapp;
            app.regSpecs = regSpecs;
            app.regSpecs.paramIGs = zeros(size(regParamList,1),1);
            for k=1:1:size(regParamList,1), app.regSpecs.paramIGs(k,1) = regParamList{k,2}; end
            app.regParamList = regParamList;
            app.RegParamDD.Items = regParamList(:,1);

            app.nlinfitSpecs = app.regSpecs.nlinfitSpecs;
            app.lsqcurvefitSpecs = app.regSpecs.lsqcurvefitSpecs;
            app.fminsearchSpecs = app.regSpecs.fminsearchSpecs;
            app.fminconSpecs = app.regSpecs.fminconSpecs;

            app.RegSpecB = RegSpecB;

            if isempty(app.nlinfitSpecs.DerivStep) || ((size(app.nlinfitSpecs.DerivStep,1) ~= size(regParamList,1)))
                app.lsqcurvefitSpecs.paramBnds = zeros(size(regParamList,1),2);
                app.nlinfitSpecs.DerivStep = zeros(size(regParamList,1),1);
                for k=1:1:size(regParamList,1)
                    app.nlinfitSpecs.DerivStep(k,1) = eps^(1/3);
                end
            end

            if isempty(app.lsqcurvefitSpecs.paramBnds) || ((size(app.lsqcurvefitSpecs.paramBnds,1) ~= size(regParamList,1)))
                app.lsqcurvefitSpecs.paramBnds = zeros(size(regParamList,1),2);
                for k=1:1:size(regParamList,1)
                    app.lsqcurvefitSpecs.paramBnds(k,1) = 0;
                    app.lsqcurvefitSpecs.paramBnds(k,2) = Inf;
                end
            end

            if isempty(app.fminconSpecs.paramBnds)
                app.fminconSpecs.paramBnds = zeros(size(regParamList,1),2);
                for k=1:1:size(regParamList,1)
                    app.fminconSpecs.paramBnds(k,1) = -Inf;
                    app.fminconSpecs.paramBnds(k,2) = Inf;
                end
            end

            app.RegSolverDD.Value = regSpecs.solver;
            switch regSpecs.solver
                case "nlinfit"
                    % setting parameter options
                    if isempty(app.nlinfitSpecs.DerivStep)
                        app.ParamIGVF1.Value = regParamList{1,2};
                        app.DerivStepL.Text = string(eps.^(1./3));
                    else
                        app.ParamIGVF1.Value = regParamList{1,2};
                        app.DerivStepL.Text = string(app.nlinfitSpecs.DerivStep(1,1));
                    end

                case "lsqcurvefit"
                    % set the bounds for each parameter
                    if isempty(app.lsqcurvefitSpecs.paramBnds)
                        app.ParamIGVF1.Value = regParamList{1,2};
                        app.ParamIGVF2.Value = 0;
                        app.ParamIGVF3.Value = Inf;
                    else
                        app.ParamIGVF1.Value = regParamList{1,2};
                        if isnan(app.lsqcurvefitSpecs.paramBnds(1,1))
                            app.ParamIGVF2.Value = -Inf;
                        else
                            app.ParamIGVF2.Value = app.lsqcurvefitSpecs.paramBnds(1,1);
                        end
                        if isnan(app.lsqcurvefitSpecs.paramBnds(1,2))
                            app.ParamIGVF3.Value = Inf;
                        else
                            app.ParamIGVF3.Value = app.lsqcurvefitSpecs.paramBnds(1,2);
                        end
                    end

                case "fminsearch"
                    if isempty(app.regSpecs.DerivStep)
                        app.ParamIGVF1.Value = regParamList{1,2};
                    end

                case "fmincon"
                    if isempty(paramBnds)
                        app.ParamIGVF1.Value = regParamList{1,2};
                        app.ParamIGVF2.Value = -Inf;
                        app.ParamIGVF3.Value = Inf;
                    else
                        app.ParamIGVF1.Value = regParamList{1,2};
                        if isnan(app.fminconSpecs.paramBnds(1,1))
                            app.ParamIGVF2.Value = -Inf;
                        else
                            app.ParamIGVF2.Value = app.fminconSpecs.paramBnds(1,1);
                        end
                        if isnan(app.fminconSpecs.paramBnds(1,2))
                            app.ParamIGVF3.Value = Inf;
                        else
                            app.ParamIGVF3.Value = app.fminconSpecs.paramBnds(1,2);
                        end
                    end
            end

            app.RegSolverDDValueChanged();
            app.RegParamDDValueChanged();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.RegSpecB.Enable = true;
            delete(app);
        end

        % Button pushed function: RegSpecUB
        function RegSpecUBPushed(app, event)
            solverName = app.RegSolverDD.Value;
            app.regSpecs.params = app.regParamList{:,1};

            app.regSpecs.solver = solverName;
            app.regSpecs.nlinfitSpecs = app.nlinfitSpecs;
            app.regSpecs.lsqcurvefitSpecs = app.lsqcurvefitSpecs;
            app.regSpecs.fminsearchSpecs = app.fminsearchSpecs;
            app.regSpecs.fminconSpecs = app.fminconSpecs;

            updateRegSpecs(app.CallingApp,app.regSpecs); 
        end

        % Button pushed function: RegParamSpecUB
        function RegParamSpecUBButtonPushed(app, event)
            solverName = app.RegSolverDD.Value;
            paramName = app.RegParamDD.Value;
            initVal = double(string(app.ParamIGVF1.Value));

            switch solverName
                case "nlinfit"
                    derivStep = double(string(app.ParamIGVF4.Value));
                    for k=1:1:size(app.regParamList,1)
                        if strcmp(app.regParamList{k,1},paramName)
                            app.regSpecs.paramIGs(k,1) = initVal;
                            if isnan(derivStep)
                                app.nlinfitSpecs.DerivStep(k,1) = eps^(1/3);
                            else
                                app.nlinfitSpecs.DerivStep(k,1) = derivStep;
                            end
                            break;
                        end
                    end

                case "lsqcurvefit"
                    loBnd = app.ParamIGVF2.Value;
                    upBnd = app.ParamIGVF3.Value;
                    for k=1:1:size(app.regParamList,1)
                        if strcmp(app.regParamList{k,1},paramName)
                            app.regSpecs.paramIGs(k,1) = initVal;
                            app.lsqcurvefitSpecs.paramBnds(k,1) = loBnd;
                            app.lsqcurvefitSpecs.paramBnds(k,2) = upBnd;
                            break;
                        end
                    end

                case "fminsearch"
                    for k=1:1:size(app.regParamList,1)
                        if strcmp(app.regParamList{k,1},paramName)
                            app.regSpecs.paramIGs(k,1) = initVal;
                            break;
                        end
                    end

                case "fmincon"
                    loBnd = app.ParamIGVF2.Value;
                    upBnd = app.ParamIGVF3.Value;
                    for k=1:1:size(app.regParamList,1)
                        if strcmp(app.regParamList{k,1},paramName)
                            app.regSpecs.paramIGs(k,1) = initVal;
                            app.fminconSpecs.paramBnds(k,1) = loBnd;
                            app.fminconSpecs.paramBnds(k,2) = upBnd;
                            break;
                        end
                    end
            end

            updateRegSpecs(app.CallingApp,app.regSpecs); 
        end

        % Value changed function: RegParamDD
        function RegParamDDValueChanged(app, event)
            solverName = app.RegSolverDD.Value;
            
            paramName = string(app.RegParamDD.Value);
            for k=1:1:size(app.regParamList,1)
                if strcmp(app.regParamList{k,1},paramName)
                    app.ParamIGVF1.Value = double(app.regSpecs.paramIGs(k,1));
                    
                    switch solverName
                        case "nlinfit"
                            app.ParamIGVF4ValueChanged();

                        case "lsqcurvefit"
                            if isnan(app.lsqcurvefitSpecs.paramBnds(k,1))
                                app.ParamIGVF2.Value = -Inf;
                            else
                                app.ParamIGVF2.Value = app.lsqcurvefitSpecs.paramBnds(k,1);
                            end
                            if isnan(app.lsqcurvefitSpecs.paramBnds(k,2))
                                app.ParamIGVF3.Value = Inf;
                            else
                                app.ParamIGVF3.Value = app.lsqcurvefitSpecs.paramBnds(k,2);
                            end

                        case "fminsearch"


                        case "fmincon"
                            if isnan(app.fminconSpecs.paramBnds(1,1))
                                app.ParamIGVF2.Value = -Inf;
                            else
                                app.ParamIGVF2.Value = app.fminconSpecs.paramBnds(1,1);
                            end
                            if isnan(app.fminconSpecs.paramBnds(1,2))
                                app.ParamIGVF3.Value = Inf;
                            else
                                app.ParamIGVF3.Value = app.fminconSpecs.paramBnds(1,2);
                            end

                    end
                end
            end
        end

        % Value changed function: ParamIGVF4
        function ParamIGVF4ValueChanged(app, event)
            solverName = app.RegSolverDD.Value;
            paramName = app.RegParamDD.Value;

            switch solverName
                case "nlinfit"
                    value = app.ParamIGVF4.Value;
                    if strcmp(value,"")
                        app.DerivStepL.Text = string(eps^(1/3));
                        value = (eps)^(1/3);
                    else
                        app.DerivStepL.Text = value;
                    end

                    for k=1:1:size(app.regParamList,1)
                        if strcmp(app.regParamList{k,1},paramName)
                            app.nlinfitSpecs.DerivStep(k) = value;
                        end
                    end
            end

        end

        % Value changed function: RegSpecDD1
        function RegSpecDD1ValueChanged(app, event)
            solverName = app.RegSolverDD.Value;

            switch solverName
                case "nlinfit"
                    wtFuncName = app.RegSpecDD1.Value;
                    switch wtFuncName
                        case 'None'
                            wtFunc = '';
                            defTuneConst = "N/A";
                            wtFuncText = "N/A";
                            app.RegSpecDD2.Value = 'Default';
                            app.RegSpecValL2.Text = "N/A";
                        case 'Andrews'
                            wtFunc = 'andrews';
                            defTuneConst = 1.339;
                            wtFuncText = "$w(r) = I[|r|<pi]*\frac{sin(r)}{r}$";
                        case 'Bisquare'
                            wtFunc = 'bisquare';
                            defTuneConst = 4.685;
                            wtFuncText = "$w(r) = I[|r|<1]*(1-r^2)^2$";
                        case 'Cauchy'
                            wtFunc = 'cauchy';
                            defTuneConst = 2.385;
                            wtFuncText = "$w(r) = \frac{1}{(1+r^2)}$";
                        case 'Fair'
                            wtFunc = 'fair';
                            defTuneConst = 1.400;
                            wtFuncText = "$w(r) = \frac{1}{(1+|r|)}$";
                        case 'Huber'
                            wtFunc = 'huber';
                            defTuneConst = 1.345;
                            wtFuncText = "$w(r) = \frac{1}{\max(1,|r|)}$";
                        case 'Logistic'
                            wtFunc = 'logistic';
                            defTuneConst = 1.205;
                            wtFuncText = "$w(r) = \frac{\tanh(r)}{r}$";
                        case 'Talwar'
                            wtFunc = 'talwar';
                            defTuneConst = 2.795;
                            wtFuncText = "$w(r) = I[|r|<1]$";
                        case 'Welsch'
                            wtFunc = 'welsch';
                            defTuneConst = 2.985;
                            wtFuncText = "$w(r) = e^{-r^2}$";
                    end
        
                    app.nlinfitSpecs.RobustWgtFun = wtFunc;
                    app.RegSpecValL1.Text = wtFuncText;
                    if app.RegSpecDD2.Value == "Default"
                        app.nlinfitSpecs.Tune = [];
                        app.RegSpecValL2.Text = char(string(defTuneConst));
                    else
                        app.nlinfitSpecs.Tune = double(string(app.RegSpecDD2.Value));
                        app.RegSpecValL2.Text = string(app.RegSpecDD2.Value);
                    end

                case "lsqcurvefit"


                case "fminsearch"


                case "fmincon"

            end
        end

        % Value changed function: RegSolverDD
        function RegSolverDDValueChanged(app, event)
            solverName = app.RegSolverDD.Value;
            switch solverName
                case "nlinfit"
                    app.RegSpecL1.Text = "Weight Function";
                    app.RegSpecL2.Text = "Tuning Constant";
                    app.RegSpecL3.Text = "Termination Tolerance on Residual Sum of Squares";
                    app.RegSpecL4.Text = "Termination Tolerance on Estimated Coefficients";
                    app.RegSpecL5.Text = "Maximum Iterations";
                   app.RegSpecCB7.Text = "Check for NaN and Inf Values";

                    for k=1:1:5
                        app.("RegSpecL"+k).Enable = true;
                        app.("RegSpecL"+k).Visible = true;
                    end
                    app.RegSpecL6.Enable = false;
                    app.RegSpecL6.Visible = false;
                    app.RegSpecDD1AB.Enable = false;
                    app.RegSpecDD1AB.Visible = false;
                    app.RegSpecDD1RB.Enable = false;
                    app.RegSpecDD1RB.Visible = false;
                    app.ParamIGVF2.Enable = false;
                    app.ParamIGVF2.Visible = false;
                    app.ParamIGL2.Visible = false;
                    app.ParamIGVF3.Enable = false;
                    app.ParamIGVF3.Visible = false;
                    app.ParamIGL3.Visible = false;
                    app.ParamIGVF4.Enable = true;
                    app.ParamIGVF4.Visible = true;
                    app.ParamIGL4.Visible = true;
                    app.DerivStepL.Visible = true;

                    app.RegSpecDD1.Items = {'None','Andrews','Bisquare','Cauchy', ...
                        'Fair','Huber','Logicstic','Talwar','Welsch'};
                    match = strcmp(lower(app.RegSpecDD1.Items),app.nlinfitSpecs.RobustWgtFun);
                    if ~any(match), funVal = 'None'; else, funVal = app.RegSpecDD1.Items{match}; end
                    app.RegSpecDD1.Value = funVal;
                    if isempty(app.nlinfitSpecs.Tune)
                        app.RegSpecDD2.Items = {'Default'};
                        app.RegSpecDD2.Value = 'Default';
                    else
                        app.RegSpecDD2.Items = {'Default',char(string(app.nlinfitSpecs.Tune))};
                        app.RegSpecDD2.Value = char(string(app.nlinfitSpecs.Tune));
                    end
                    app.RegSpecDD1ValueChanged();

                    app.RegSpecVF3.Value = app.nlinfitSpecs.TolFun;
                    app.RegSpecVF4.Value = app.nlinfitSpecs.TolX;

                    app.RegSpecSpin5.Value = app.nlinfitSpecs.MaxIter;
                    if strcmp(app.nlinfitSpecs.FunValCheck,"off"), checkVal = false; else, checkVal = true; end
                    app.RegSpecCB7.Value = checkVal;

                    app.RegSpecDD1AB.Enable = false;
                    app.RegSpecDD1RB.Enable = false;

                    % setting parameter options
                    if isempty(app.nlinfitSpecs.DerivStep)
                        app.ParamIGVF1.Value = app.regParamList{1,2};
                        app.DerivStepL.Text = string(eps.^(1./3));
                    else
                        app.ParamIGVF1.Value = app.regParamList{1,2};
                        app.DerivStepL.Text = string(app.nlinfitSpecs.DerivStep(1,1));
                    end
                case "lsqcurvefit"
                    app.RegSpecL1.Text = "Regression Progress Plotting";
                    app.RegSpecL2.Text = "Algorithm";
                    app.RegSpecL3.Text = "Termination Tolerance on Objective Function";
                    app.RegSpecL4.Text = "Termination Tolerance on Optimization Variables";
                    app.RegSpecL5.Text = "Maximum Iterations";
                    app.RegSpecL6.Text = "Maximum Function Evaluations";
                    app.RegSpecCB7.Text = "Check for Inf and Complex Function Values";

                    for k=[1,3,4,5,6]
                        app.("RegSpecL"+k).Enable = true;
                        app.("RegSpecL"+k).Visible = true;                        
                    end
                    app.RegSpecL2.Enable = true;
                    app.RegSpecL2.Visible = true;
                    app.ParamIGVF2.Enable = true;
                    app.ParamIGVF2.Visible = true;
                    app.ParamIGL2.Visible = true;
                    app.ParamIGVF3.Enable = true;
                    app.ParamIGVF3.Visible = true;
                    app.ParamIGL3.Visible = true;
                    app.ParamIGVF4.Enable = false;
                    app.ParamIGVF4.Visible = false;
                    app.ParamIGL4.Visible = false;
                    app.DerivStepL.Visible = false;

                    app.RegSpecDD1.Items = {'Optimization Variables','Objective Function Count','Objective Function Value'};
                    app.RegSpecDD1.Value = app.RegSpecDD1.Items(1);

                    app.RegSpecDD2.Items = {'Trust-Region-Reflective','Levenberg-Marquardt','Interior-Point'};
                    match = strcmp(lower(app.RegSpecDD2.Items),app.lsqcurvefitSpecs.Algorithm);
                    funVal = app.RegSpecDD2.Items{match};
                    app.RegSpecDD2.Value = funVal;
                    app.RegSpecDD2.Value = app.lsqcurvefitSpecs.Algorithm;

                    app.RegSpecVF3.Value = app.lsqcurvefitSpecs.TolFun;
                    app.RegSpecVF4.Value = app.lsqcurvefitSpecs.TolX;
                    app.RegSpecSpin5.Value = app.lsqcurvefitSpecs.MaxIter;
                    app.RegSpecSpin6.Value = app.lsqcurvefitSpecs.MaxFunEvals;

                    if strcmp(app.lsqcurvefitSpecs.FunValCheck,"off"), checkVal = false; else, checkVal = true; end
                    app.RegSpecCB7.Value = checkVal;

                    app.RegSpecDD1AB.Enable = true;
                    app.RegSpecDD1RB.Enable = true;

                    % set the bounds for each parameter
                    if isempty(app.lsqcurvefitSpecs.paramBnds)
                        app.ParamIGVF1.Value = app.regParamList{1,2};
                        app.ParamIGVF2.Value = 0;
                        app.ParamIGVF3.Value = Inf;
                    else
                        app.ParamIGVF1.Value = app.regParamList{1,2};
                        if isnan(app.lsqcurvefitSpecs.paramBnds(1,1))
                            app.ParamIGVF2.Value = -Inf;
                        else
                            app.ParamIGVF2.Value = app.lsqcurvefitSpecs.paramBnds(1,1);
                        end
                        if isnan(app.lsqcurvefitSpecs.paramBnds(1,2))
                            app.ParamIGVF3.Value = Inf;
                        else
                            app.ParamIGVF3.Value = app.lsqcurvefitSpecs.paramBnds(1,2);
                        end
                    end

                case "fminsearch"
                    app.RegSpecL1.Text = "Regression Progress Plotting";
                    app.RegSpecL3.Text = "Termination Tolerance on Objective Function";
                    app.RegSpecL4.Text = "Termination Tolerance on Optimization Variables";
                    app.RegSpecL5.Text = "Maximum Iterations";
                    app.RegSpecL6.Text = "Maximum Function Evaluations";
                    app.RegSpecCB7.Text = "Check for Inf and Complex Function Values";

                    for k=[1,3,4,5,6]
                        app.("RegSpecL"+k).Enable = true;
                        app.("RegSpecL"+k).Visible = true;                        
                    end
                    app.RegSpecL2.Enable = false;
                    app.RegSpecL2.Visible = false;
                    app.ParamIGVF2.Enable = false;
                    app.ParamIGVF2.Visible = false;
                    app.ParamIGL2.Visible = false;
                    app.ParamIGVF3.Enable = false;
                    app.ParamIGVF3.Visible = false;
                    app.ParamIGL3.Visible = false;
                    app.ParamIGVF4.Enable = false;
                    app.ParamIGVF4.Visible = false;
                    app.ParamIGL4.Visible = false;
                    app.DerivStepL.Visible = false;

                    app.RegSpecDD1.Items = {'Optimization Variables','Objective Function Count','Objective Function Value'};
                    app.RegSpecDD1.Value = app.RegSpecDD1.Items(1);

                    app.RegSpecVF3.Value = app.fminsearchSpecs.TolFun;
                    app.RegSpecVF4.Value = app.fminsearchSpecs.TolX;
                    app.RegSpecSpin5.Value = app.fminsearchSpecs.MaxIter;
                    app.RegSpecSpin6.Value = app.fminsearchSpecs.MaxFunEvals;

                    if strcmp(app.fminsearchSpecs.FunValCheck,"off"), checkVal = false; else, checkVal = true; end
                    app.RegSpecCB7.Value = checkVal;

                    app.RegSpecDD1AB.Enable = true;
                    app.RegSpecDD1RB.Enable = true;

                    if isempty(app.regSpecs.DerivStep)
                        app.ParamIGVF1.Value = app.regParamList{1,2};
                    end
                case "fmincon"
                    app.RegSpecL1.Text = "Regression Progress Plotting";
                    app.RegSpecL2.Text = "Algorithm";
                    app.RegSpecL3.Text = "Termination Tolerance on Objective Function";
                    app.RegSpecL4.Text = "Termination Tolerance on Optimization Variables";
                    app.RegSpecL5.Text = "Maximum Iterations";
                    app.RegSpecL6.Text = "Maximum Function Evaluations";
                    app.RegSpecCB7.Text = "Check for Inf and Complex Function Values";

                    for k=[1,3,4,5,6]
                        app.("RegSpecL"+k).Enable = true;
                        app.("RegSpecL"+k).Visible = true;                        
                    end
                    app.RegSpecL2.Enable = true;
                    app.RegSpecL2.Visible = true;
                    app.ParamIGVF2.Enable = true;
                    app.ParamIGVF2.Visible = true;
                    app.ParamIGL2.Visible = true;
                    app.ParamIGVF3.Enable = true;
                    app.ParamIGVF3.Visible = true;
                    app.ParamIGL3.Visible = true;
                    app.ParamIGVF4.Enable = false;
                    app.ParamIGVF4.Visible = false;
                    app.ParamIGL4.Visible = false;
                    app.DerivStepL.Visible = false;

                    app.RegSpecDD1.Items = {'Optimization Variables','Objective Function Count','Objective Function Value'};
                    app.RegSpecDD1.Value = app.RegSpecDD1.Items(1);

                    app.RegSpecDD2.Items = {'Interior-Point','Trust-Region-Reflective','SQP','Active-Set'};
                    match = strcmp(lower(app.RegSpecDD2.Items),app.fminconSpecs.Algorithm);
                    funVal = app.RegSpecDD2.Items{match};
                    app.RegSpecDD2.Value = funVal;

                    app.RegSpecVF3.Value = app.fminconSpecs.TolFun;
                    app.RegSpecVF4.Value = app.fminconSpecs.TolX;
                    app.RegSpecSpin5.Value = app.fminconSpecs.MaxIter;
                    app.RegSpecSpin6.Value = app.fminconSpecs.MaxFunEvals;

                    if strcmp(app.fminconSpecs.FunValCheck,"off"), checkVal = false; else, checkVal = true; end
                    app.RegSpecCB7.Value = checkVal;

                    app.RegSpecDD1AB.Enable = true;
                    app.RegSpecDD1RB.Enable = true;

                    if isempty(paramBnds)
                        app.ParamIGVF1.Value = app.regParamList{1,2};
                        app.ParamIGVF2.Value = -Inf;
                        app.ParamIGVF3.Value = Inf;
                    else
                        app.ParamIGVF1.Value = app.regParamList{1,2};
                        if isnan(app.fminconSpecs.paramBnds(1,1))
                            app.ParamIGVF2.Value = -Inf;
                        else
                            app.ParamIGVF2.Value = app.fminconSpecs.paramBnds(1,1);
                        end
                        if isnan(app.fminconSpecs.paramBnds(1,2))
                            app.ParamIGVF3.Value = Inf;
                        else
                            app.ParamIGVF3.Value = app.fminconSpecs.paramBnds(1,2);
                        end
                    end
            end

            app.RegParamDDValueChanged();
        end

        % Button pushed function: RegSpecDD1AB
        function RegSpecDD1ABButtonPushed(app, event)
            solverName = app.RegSolverDD.Value;
            
            switch solverName
                case "fminsearch"
                    regProgPlot = string(app.RegSpecDD1.Value);
                    plots = app.fminsearchSpecs.PlotFcns;
        
                    app.fminsearchSpecs.PlotFcns = [plots,regProgPlot];
            end
        end

        % Button pushed function: RegSpecDD1RB
        function RegSpecDD1RBButtonPushed(app, event)
            regProgPlot = string(app.RegSpecDD1.Value);
            plots = app.fminsearchSpecs.PlotFcns;

            for k=1:1:length(plots)
                if strcmp(plots(k),regProgPlot)
                    plots(k) = [];
                end
            end
            app.fminsearchSpecs.PlotFcns = plots;
        end

        % Value changed function: RegSpecDD2
        function RegSpecDD2ValueChanged(app, event)
            value = app.RegSpecDD2.Value;
            solverName = app.RegSolverDD.Value;
            
            switch solverName
                case "nlinfit"
                    app.RegSpecDD1ValueChanged();

            end
        end

        % Value changed function: RegSpecVF3
        function RegSpecVF3ValueChanged(app, event)
            value = app.RegSpecVF3.Value;
            solverName = app.RegSolverDD.Value;
            
            switch solverName
                case "nlinfit"
                    app.nlinfitSpecs.TolFun = double(string(value));

            end
        end

        % Value changed function: RegSpecVF4
        function RegSpecVF4ValueChanged(app, event)
            value = app.RegSpecVF4.Value;
            solverName = app.RegSolverDD.Value;
            
            switch solverName
                case "nlinfit"
                    app.nlinfitSpecs.TolX = double(string(value));

            end
        end

        % Value changed function: RegSpecSpin5
        function RegSpecSpin5ValueChanged(app, event)
            value = app.RegSpecSpin5.Value;
            solverName = app.RegSolverDD.Value;
            
            switch solverName
                case "nlinfit"
                    app.nlinfitSpecs.MaxIter = double(string(value));

            end
        end

        % Value changed function: RegSpecCB7
        function RegSpecCB7ValueChanged(app, event)
            value = app.RegSpecCB7.Value;
            solverName = app.RegSolverDD.Value;
            
            switch solverName
                case "nlinfit"
                    if value
                        app.nlinfitSpecs.FunValCheck = 'on';
                    else
                        app.nlinfitSpecs.FunValCheck = 'off';
                    end

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RegressionParameterSpecificationsPanel = uipanel(app.GridLayout);
            app.RegressionParameterSpecificationsPanel.Title = 'Regression Parameter Specifications';
            app.GridLayout2 = uigridlayout(app.RegressionParameterSpecificationsPanel);
            app.ParameterLabel = uilabel(app.GridLayout2);
            app.ParameterLabel.Text = 'Parameter';
            app.RegParamDD = uidropdown(app.GridLayout2);
            app.RegParamDD.Items = {};
            app.RegParamDD.Editable = 'on';
            app.RegParamDD.ValueChangedFcn = createCallbackFcn(app, @RegParamDDValueChanged, true);
            app.RegParamDD.Value = {};
            app.DerivStepL = uilabel(app.GridLayout2);
            app.DerivStepL.Text = '';
            app.RegParamSpecUB = uibutton(app.GridLayout2, 'push');
            app.RegParamSpecUB.ButtonPushedFcn = createCallbackFcn(app, @RegParamSpecUBButtonPushed, true);
            app.RegParamSpecUB.Text = 'Update';
            app.InitialGuessLabel = uilabel(app.GridLayout2);
            app.InitialGuessLabel.Text = 'Initial Guess';
            app.ParamIGVF1 = uieditfield(app.GridLayout2, 'numeric');
            app.ParamIGVF1.Value = 1;
            app.ParamIGL2 = uilabel(app.GridLayout2);
            app.ParamIGL2.Text = 'Lower Bound';
            app.ParamIGVF2 = uieditfield(app.GridLayout2, 'numeric');
            app.ParamIGVF3 = uieditfield(app.GridLayout2, 'numeric');
            app.ParamIGVF3.Value = 1000;
            app.ParamIGL3 = uilabel(app.GridLayout2);
            app.ParamIGL3.Text = 'Upper Bound';
            app.ParamIGL4 = uilabel(app.GridLayout2);
            app.ParamIGL4.Text = 'Relative Difference for Finite Difference Gradient';
            app.ParamIGVF4 = uieditfield(app.GridLayout2, 'text');
            app.ParamIGVF4.InputType = 'alphanumerics';
            app.ParamIGVF4.ValueChangedFcn = createCallbackFcn(app, @ParamIGVF4ValueChanged, true);
            app.ParamIGVF4.Placeholder = 'Default';
            app.RegSpecsP = uipanel(app.GridLayout);
            app.RegSpecsP.Title = 'Regression Specifications';
            app.GridLayout3 = uigridlayout(app.RegSpecsP);
            app.RegSpecValL1 = uilabel(app.GridLayout3);
            app.RegSpecValL1.Text = 'N/A';
            app.RegSpecVF3 = uieditfield(app.GridLayout3, 'numeric');
            app.RegSpecVF3.ValueChangedFcn = createCallbackFcn(app, @RegSpecVF3ValueChanged, true);
            app.RegSpecVF3.Value = 1e-08;
            app.RegSpecVF4 = uieditfield(app.GridLayout3, 'numeric');
            app.RegSpecVF4.ValueChangedFcn = createCallbackFcn(app, @RegSpecVF4ValueChanged, true);
            app.RegSpecVF4.Value = 1e-08;
            app.RegSpecDD1 = uidropdown(app.GridLayout3);
            app.RegSpecDD1.Items = {'None', 'Andrews', 'Bisquare', 'Cauchy', 'Fair', 'Huber', 'Logicstic', 'Talwar', 'Welsch'};
            app.RegSpecDD1.Editable = 'on';
            app.RegSpecDD1.ValueChangedFcn = createCallbackFcn(app, @RegSpecDD1ValueChanged, true);
            app.RegSpecDD1.Value = 'None';
            app.RegSpecValL2 = uilabel(app.GridLayout3);
            app.RegSpecValL2.Text = 'N/A';
            app.RegSpecDD2 = uidropdown(app.GridLayout3);
            app.RegSpecDD2.Items = {'Default'};
            app.RegSpecDD2.Editable = 'on';
            app.RegSpecDD2.ValueChangedFcn = createCallbackFcn(app, @RegSpecDD2ValueChanged, true);
            app.RegSpecDD2.Value = 'Default';
            app.RegSpecSpin5 = uispinner(app.GridLayout3);
            app.RegSpecSpin5.ValueChangedFcn = createCallbackFcn(app, @RegSpecSpin5ValueChanged, true);
            app.RegSpecSpin5.Value = 100;
            app.RegSpecCB7 = uicheckbox(app.GridLayout3);
            app.RegSpecCB7.ValueChangedFcn = createCallbackFcn(app, @RegSpecCB7ValueChanged, true);
            app.RegSpecCB7.Text = 'Check for NaN and Inf Values';
            app.RegSpecUB = uibutton(app.GridLayout3, 'push');
            app.RegSpecUB.ButtonPushedFcn = createCallbackFcn(app, @RegSpecUBPushed, true);
            app.RegSpecUB.Text = 'Update Regression Specifications';
            app.RegressionSolverDropDownLabel = uilabel(app.GridLayout3);
            app.RegressionSolverDropDownLabel.Text = 'Regression Solver';
            app.RegSolverDD = uidropdown(app.GridLayout3);
            app.RegSolverDD.Items = {'nlinfit', 'lsqcurvefit'};
            app.RegSolverDD.ValueChangedFcn = createCallbackFcn(app, @RegSolverDDValueChanged, true);
            app.RegSolverDD.Value = 'nlinfit';
            app.RegSpecL1 = uilabel(app.GridLayout3);
            app.RegSpecL1.Text = 'Weight Function';
            app.RegSpecL2 = uilabel(app.GridLayout3);
            app.RegSpecL2.Text = 'Tuning Constant';
            app.RegSpecL3 = uilabel(app.GridLayout3);
            app.RegSpecL3.Text = 'Termination Tolerance on Residual Sum of Squares';
            app.RegSpecL4 = uilabel(app.GridLayout3);
            app.RegSpecL4.Text = 'Termination Tolerance on Estimated Coefficients';
            app.RegSpecL5 = uilabel(app.GridLayout3);
            app.RegSpecL5.Text = 'Maximum Iterations';
            app.RegSpecL6 = uilabel(app.GridLayout3);
            app.RegSpecL6.Enable = 'off';
            app.RegSpecL6.Visible = 'off';
            app.RegSpecL6.Text = 'Maximum Number of Function Evaluations';
            app.RegSpecSpin6 = uispinner(app.GridLayout3);
            app.RegSpecSpin6.Enable = 'off';
            app.RegSpecSpin6.Visible = 'off';
            app.RegSpecSpin6.Value = 100;
            app.RegSpecDD1AB = uibutton(app.GridLayout3, 'push');
            app.RegSpecDD1AB.ButtonPushedFcn = createCallbackFcn(app, @RegSpecDD1ABButtonPushed, true);
            app.RegSpecDD1AB.Text = 'Add';
            app.RegSpecDD1RB = uibutton(app.GridLayout3, 'push');
            app.RegSpecDD1RB.ButtonPushedFcn = createCallbackFcn(app, @RegSpecDD1RBButtonPushed, true);
            app.RegSpecDD1RB.Text = 'Remove';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RegSpecsWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end